classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectReferenceButton         matlab.ui.control.Button
        SelectTargetButton            matlab.ui.control.Button
        DetectionMethodDropDownLabel  matlab.ui.control.Label
        DetectionMethodDropDown       matlab.ui.control.DropDown
        estimateGeometricTransformCheckBox  matlab.ui.control.CheckBox
        WebcamButton                  matlab.ui.control.Button
        CropButton                    matlab.ui.control.Button
        FindKeypointsButton           matlab.ui.control.Button
        ReferenceImage                matlab.ui.control.UIAxes
        TargetImage                   matlab.ui.control.UIAxes
        ResultImage                   matlab.ui.control.UIAxes
        WebcamButton_2                matlab.ui.control.Button
    end

    
    properties (Access = private)
        reference_image_data;
        target_image_data;
        cam = webcam(1);
        camActive = false;
    end
    
    methods (Access = private)
        
        function result = handle_webcam(app, target)
            if(~app.camActive)
                frame = snapshot(app.cam);
                preview(app.cam, image(target,frame));
                app.camActive = true;
                result = frame;
            else
                result = snapshot(app.cam);
                closePreview(app.cam);
                app.camActive = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectReferenceButton
        function SelectReferenceButtonPushed(app, event)
            [reference_file,reference_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(reference_file,0)
                return;
            else
                app.reference_image_data = imread(fullfile(reference_path, reference_file));
                imshow(app.reference_image_data,'Parent',app.ReferenceImage);
            end
        end

        % Button pushed function: SelectTargetButton
        function SelectTargetButtonPushed(app, event)
            [target_file,target_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(target_file,0)
                return;
            else
                app.target_image_data = imread(fullfile(target_path, target_file));
                imshow(app.target_image_data,'Parent',app.TargetImage);
            end
        end

        % Button pushed function: FindKeypointsButton
        function FindKeypointsButtonPushed(app, event)
            if(isempty(app.reference_image_data)) 
                msgbox("Reference Image is missing");
                return
            end
            if (isempty(app.target_image_data))
                msgbox("Target Image is missing");
                return
            end
            
            cla(app.ResultImage);
            
            reference_image_data_grey = rgb2gray(app.reference_image_data);
            target_image_data_grey = rgb2gray(app.target_image_data);
            
            switch app.DetectionMethodDropDown.Value
                case "BRISK"
                    referencePoints = detectBRISKFeatures(reference_image_data_grey);
                    targetPoints = detectBRISKFeatures(target_image_data_grey);
                case "FAST"
                    referencePoints = detectFASTFeatures(reference_image_data_grey);
                    targetPoints = detectFASTFeatures(target_image_data_grey);
                case "Harris"
                    referencePoints = detectHarrisFeatures(reference_image_data_grey);
                    targetPoints = detectHarrisFeatures(target_image_data_grey);
                case "MinEigen"
                    referencePoints = detectMinEigenFeatures(reference_image_data_grey);
                    targetPoints = detectMinEigenFeatures(target_image_data_grey);
                case "MSER"
                    referencePoints = detectMSERFeatures(reference_image_data_grey);
                    targetPoints = detectMSERFeatures(target_image_data_grey);
                case "ORB"
                    referencePoints = detectORBFeatures(reference_image_data_grey);
                    targetPoints = detectORBFeatures(target_image_data_grey);
                case "SURF"
                    referencePoints = detectSURFFeatures(reference_image_data_grey);
                    targetPoints = detectSURFFeatures(target_image_data_grey);
                case "KAZE"
                    referencePoints = detectKAZEFeatures(reference_image_data_grey);
                    targetPoints = detectKAZEFeatures(target_image_data_grey);
            end
            
            hold(app.ReferenceImage);
            plot(referencePoints.selectStrongest(50), app.ReferenceImage);
            hold(app.ReferenceImage);
            
            hold(app.TargetImage);
            plot(targetPoints.selectStrongest(50), app.TargetImage);
            hold(app.TargetImage);
            
            
            [referenceFeatures, referencePoints] = extractFeatures(reference_image_data_grey, referencePoints);
            [targetFeatures, targetPoints] = extractFeatures(target_image_data_grey, targetPoints);
            
            
            pairs = matchFeatures(referenceFeatures, targetFeatures);
            matchedReferencePoints = referencePoints(pairs(:, 1), :);
            matchedTargetPoints = targetPoints(pairs(:, 2), :);
            
            
            if((matchedTargetPoints.length > 2) && (matchedReferencePoints.length > 2))
                
                
                if(app.estimateGeometricTransformCheckBox.Value == 0)
                showMatchedFeatures(app.reference_image_data, app.target_image_data, matchedReferencePoints, ...
                matchedTargetPoints, 'montage', "Parent", app.ResultImage);
                return;
                end
                
                [tform, estimatedReferencePoints, estimatedTargetPoints] = ...
                    estimateGeometricTransform(matchedReferencePoints, matchedTargetPoints, 'affine');
                
                foundPolygon = [1, 1;...                                                                % top-left
                            size(app.reference_image_data, 2), 1;...                                    % top-right
                            size(app.reference_image_data, 2), size(app.reference_image_data, 1);...    % bottom-right
                            1, size(app.reference_image_data, 1);...                                    % bottom-left
                            1, 1];                                                                      % top-left again to close the polygon
            
                newFoundPolygon = transformPointsForward(tform, foundPolygon);
                imshow(app.target_image_data, 'Parent', app.ResultImage);
                hold(app.ResultImage);
                line(app.ResultImage, newFoundPolygon(:, 1), newFoundPolygon(:, 2), 'Color', 'y');
                hold(app.ResultImage);
                
                % TODO display this
                showMatchedFeatures(app.reference_image_data, app.target_image_data, estimatedReferencePoints, ...
                    estimatedTargetPoints, 'montage');
                
            else
                msgbox("Not enough features found for matching.");
            end
            
            
        end

        % Button pushed function: WebcamButton
        function WebcamButtonPushed(app, event)
            app.target_image_data = handle_webcam(app, app.TargetImage);
        end

        % Button pushed function: WebcamButton_2
        function WebcamButton_2Pushed(app, event)
            app.reference_image_data = handle_webcam(app, app.ReferenceImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectReferenceButton = uibutton(app.UIFigure, 'push');
            app.SelectReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceButtonPushed, true);
            app.SelectReferenceButton.Text = {'Select Reference'; ''};
            app.SelectTargetButton = uibutton(app.UIFigure, 'push');
            app.SelectTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetButtonPushed, true);
            app.SelectTargetButton.Text = {'Select Target'; ''};
            app.DetectionMethodDropDownLabel = uilabel(app.UIFigure);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.UIFigure);
            app.DetectionMethodDropDown.Items = {'BRISK', 'FAST', 'Harris', 'MinEigen', 'MSER', 'ORB', 'SURF', 'KAZE'};
            app.DetectionMethodDropDown.Value = 'BRISK';
            app.estimateGeometricTransformCheckBox = uicheckbox(app.UIFigure);
            app.estimateGeometricTransformCheckBox.Text = 'estimateGeometricTransform';
            app.WebcamButton = uibutton(app.UIFigure, 'push');
            app.WebcamButton.ButtonPushedFcn = createCallbackFcn(app, @WebcamButtonPushed, true);
            app.WebcamButton.Text = {'Webcam'; ''};
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.Text = 'Crop';
            app.FindKeypointsButton = uibutton(app.UIFigure, 'push');
            app.FindKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindKeypointsButtonPushed, true);
            app.FindKeypointsButton.Text = 'Find Keypoints';
            app.ReferenceImage = uiaxes(app.UIFigure);
            title(app.ReferenceImage, {''; ''})
            xlabel(app.ReferenceImage, {''; ''})
            ylabel(app.ReferenceImage, {''; ''})
            app.ReferenceImage.XTick = [];
            app.ReferenceImage.YTick = [];
            app.TargetImage = uiaxes(app.UIFigure);
            title(app.TargetImage, {''; ''})
            xlabel(app.TargetImage, {''; ''})
            ylabel(app.TargetImage, {''; ''})
            app.TargetImage.XTick = [];
            app.TargetImage.YTick = [];
            app.ResultImage = uiaxes(app.UIFigure);
            title(app.ResultImage, {''; ''})
            xlabel(app.ResultImage, {''; ''})
            ylabel(app.ResultImage, {''; ''})
            app.ResultImage.XTick = [];
            app.ResultImage.YTick = [];
            app.WebcamButton_2 = uibutton(app.UIFigure, 'push');
            app.WebcamButton_2.ButtonPushedFcn = createCallbackFcn(app, @WebcamButton_2Pushed, true);
            app.WebcamButton_2.Text = 'Webcam';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end