classdef demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DetectionMethodDropDownLabel  matlab.ui.control.Label
        DetectionMethodDropDown       matlab.ui.control.DropDown
        PutmatchesingeometricrelationCheckBox  matlab.ui.control.CheckBox
        FindKeypointsButton           matlab.ui.control.Button
        ReferenceImage                matlab.ui.control.UIAxes
        TargetImage                   matlab.ui.control.UIAxes
        ResultImage                   matlab.ui.control.UIAxes
        MatchKeypointsButton          matlab.ui.control.Button
        ToggleWebcamButtonTarget      matlab.ui.control.StateButton
        ToggleWebcamButtonReference   matlab.ui.control.StateButton
        ReferenceImageMenuButton      matlab.ui.control.Button
        TargetImageMenuButton         matlab.ui.control.Button
        NumberofshownKeypointsSliderLabel  matlab.ui.control.Label
        NumberofshownKeypointsSlider  matlab.ui.control.Slider
        ShowBoundingBoxCheckBox       matlab.ui.control.CheckBox
        ContextMenu                   matlab.ui.container.ContextMenu
        SelectImageMenu               matlab.ui.container.Menu
        SaveImageMenu                 matlab.ui.container.Menu
        CropImageMenu                 matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        SelectImageMenu_2             matlab.ui.container.Menu
        SaveImageMenu_2               matlab.ui.container.Menu
        CropImageMenu_2               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        reference_image_data;
        target_image_data;
        
        reference_image_data_grey
        target_image_data_grey
        
        ref_im_object
        tar_im_object
        
        referencePoints
        targetPoints
        
        cam;
        camActive = false;
        
        
    end
    
    methods (Access = private)
      
        function result = handle_webcam(app, target, state)
            result = [];
            if(isempty(app.cam))
                try
                    app.cam = webcam(1);
                catch ME
                    msgbox("No Webcam found!");
                    return;
                end
                
            end
            
            if(state == 1)
                frame = snapshot(app.cam);
                preview(app.cam, image(target,frame));
                result = frame;
            else
                result = snapshot(app.cam);
                closePreview(app.cam);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'DefaultFigureVisible','off');
        end

        % Menu selected function: SelectImageMenu
        function SelectReferenceButtonPushed(app, event)
            [reference_file,reference_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(reference_file,0)
                return;
            else
                app.reference_image_data = imread(fullfile(reference_path, reference_file));
                imshow(app.reference_image_data,'Parent',app.ReferenceImage);
                cla(app.ResultImage);
                figure(app.UIFigure);
            end
        end

        % Menu selected function: SelectImageMenu_2
        function SelectTargetButtonPushed(app, event)
            [target_file,target_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(target_file,0)
                return;
            else
                app.target_image_data = imread(fullfile(target_path, target_file));
                imshow(app.target_image_data,'Parent',app.TargetImage);
                cla(app.ResultImage);
                figure(app.UIFigure);
            end
        end

        % Callback function: DetectionMethodDropDown, 
        % FindKeypointsButton
        function FindKeypointsButtonPushed(app, event)
            app.ToggleWebcamButtonReference.Value = false;
            if(isempty(app.reference_image_data)) 
                msgbox("Reference Image is missing");
                return
            end
            if (isempty(app.target_image_data))
                msgbox("Target Image is missing");
                return
            end
           
            cla(app.ReferenceImage)
            cla(app.TargetImage)
            imshow(app.target_image_data,'Parent',app.TargetImage);
            imshow(app.reference_image_data,'Parent',app.ReferenceImage);
            if(size(app.reference_image_data, 3) == 3)
                app.reference_image_data_grey = rgb2gray(app.reference_image_data);
            else
                app.reference_image_data_grey = app.reference_image_data;
            end
            
            if(size(app.target_image_data, 3) == 3)
                app.target_image_data_grey = rgb2gray(app.target_image_data);
            else
                app.target_image_data_grey = app.target_image_data;
            end
            
            
            cla(app.ResultImage);
            
            switch app.DetectionMethodDropDown.Value
                case "BRISK"
                    app.referencePoints = detectBRISKFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectBRISKFeatures(app.target_image_data_grey);
                case "FAST"
                    app.referencePoints = detectFASTFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectFASTFeatures(app.target_image_data_grey);
                case "Harris"
                    app.referencePoints = detectHarrisFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectHarrisFeatures(app.target_image_data_grey);
                case "MinEigen"
                    app.referencePoints = detectMinEigenFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectMinEigenFeatures(app.target_image_data_grey);
                case "ORB"
                    app.referencePoints = detectORBFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectORBFeatures(app.target_image_data_grey);
                case "SURF"
                    app.referencePoints = detectSURFFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectSURFFeatures(app.target_image_data_grey);
                case "KAZE"
                    app.referencePoints = detectKAZEFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectKAZEFeatures(app.target_image_data_grey);
            end
            
            hold(app.ReferenceImage, "on");
            plot(app.referencePoints.selectStrongest(round(app.NumberofshownKeypointsSlider.Value)), app.ReferenceImage);
            hold(app.ReferenceImage, "off");
            
            hold(app.TargetImage, "on");
            plot(app.targetPoints.selectStrongest(round(app.NumberofshownKeypointsSlider.Value)), app.TargetImage);
            hold(app.TargetImage, "off");


        end

        % Menu selected function: CropImageMenu
        function ReferenceCropButtonPushed(app, event)
            app.ToggleWebcamButtonReference.Value = false;
            imshow(app.reference_image_data, 'InitialMagnification', 200);
            roi = drawrectangle;
            try
                app.reference_image_data = imcrop(app.reference_image_data,roi.Position);
            catch ME
                app.reference_image_data = app.reference_image_data;
            end
            close all;
            app.ref_im_object = imshow(app.reference_image_data,'Parent',app.ReferenceImage);
            cla(app.ResultImage);
        end

        % Button pushed function: MatchKeypointsButton
        function MatchKeypointsButtonPushed(app, event)
            [referenceFeatures, app.referencePoints] = extractFeatures(app.reference_image_data_grey, app.referencePoints);
            [targetFeatures, app.targetPoints] = extractFeatures(app.target_image_data_grey, app.targetPoints);
            
            
            pairs = matchFeatures(referenceFeatures, targetFeatures, "MatchThreshold", 50, "MaxRatio",0.7);
            matchedReferencePoints = app.referencePoints(pairs(:, 1), :);
            matchedTargetPoints = app.targetPoints(pairs(:, 2), :);
            
            
            if((matchedTargetPoints.length > 2) && (matchedReferencePoints.length > 2))
                
                
                if(app.PutmatchesingeometricrelationCheckBox.Value == 0)
                    showMatchedFeatures(app.reference_image_data, app.target_image_data, matchedReferencePoints, ...
                    matchedTargetPoints, 'montage', "Parent", app.ResultImage);
                    return;
                end
                
                [tform, estimatedReferencePoints, estimatedTargetPoints] = ...
                    estimateGeometricTransform(matchedReferencePoints, matchedTargetPoints, 'affine');
                
                if(app.ShowBoundingBoxCheckBox.Value == 1)
                    foundPolygon = [1, 1;...                                                                % top-left
                                size(app.reference_image_data, 2), 1;...                                    % top-right
                                size(app.reference_image_data, 2), size(app.reference_image_data, 1);...    % bottom-right
                                1, size(app.reference_image_data, 1);...                                    % bottom-left
                                1, 1];                                                                      % top-left again to close the polygon
                
                    newFoundPolygon = transformPointsForward(tform, foundPolygon);
                    imshow(app.target_image_data, 'Parent', app.ResultImage);
                    hold(app.ResultImage, "on");
                    line(app.ResultImage, newFoundPolygon(:, 1), newFoundPolygon(:, 2), 'Color', 'y');
                    hold(app.ResultImage, "off");
                else
                    showMatchedFeatures(app.reference_image_data, app.target_image_data, estimatedReferencePoints, ...
                    estimatedTargetPoints, 'montage', "Parent", app.ResultImage);
                end
                
            else
                msgbox("Not enough features found for matching.");
            end
            
            
        end

        % Value changed function: ToggleWebcamButtonTarget
        function WebcamToggleTarget(app, event)
            tmp = handle_webcam(app, app.TargetImage, event.Value);
            if(isempty(tmp))
                return;
            else
               app.target_image_data = tmp;
            end
            
        end

        % Value changed function: ToggleWebcamButtonReference
        function WebcamToggleReference(app, event)
            tmp = handle_webcam(app, app.ReferenceImage, event.Value);
            if(isempty(tmp))
                return;
            else
               app.reference_image_data = tmp;
            end
        end

        % Menu selected function: SaveImageMenu
        function SaveReferenceButtonPushed(app, event)
            if(~isempty(app.reference_image_data))
                imsave(image(app.reference_image_data));
            end
        end

        % Menu selected function: SaveImageMenu_2
        function SaveTargetButtonPushed(app, event)
            if(~isempty(app.target_image_data))
               imsave(image(app.target_image_data));
            end
        end

        % Menu selected function: CropImageMenu_2
        function TargetCropButtonPushed(app, event)
            app.ToggleWebcamButtonTarget.Value = false;
            imshow(app.target_image_data, 'InitialMagnification', 200);
            roi = drawrectangle;
            try
                app.target_image_data = imcrop(app.target_image_data,roi.Position);
            catch ME
                app.target_image_data = app.target_image_data;
            end
            close all;
            imshow(app.target_image_data,'Parent',app.TargetImage);
            cla(app.ResultImage);
        end

        % Button pushed function: ReferenceImageMenuButton
        function ReferenceImageMenuButtonPushed(app, event)
            
        end

        % Value changed function: 
        % PutmatchesingeometricrelationCheckBox
        function PutmatchesingeometricrelationCheckBoxValueChanged(app, event)
            value = app.PutmatchesingeometricrelationCheckBox.Value;
            if(value == 1)
                app.ShowBoundingBoxCheckBox.Enable = 1;
            else
                app.ShowBoundingBoxCheckBox.Enable = 0;
                app.ShowBoundingBoxCheckBox.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DetectionMethodDropDownLabel = uilabel(app.UIFigure);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.UIFigure);
            app.DetectionMethodDropDown.Items = {'BRISK', 'FAST', 'Harris', 'MinEigen', 'ORB', 'SURF', 'KAZE'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FindKeypointsButtonPushed, true);
            app.DetectionMethodDropDown.Value = 'BRISK';
            app.PutmatchesingeometricrelationCheckBox = uicheckbox(app.UIFigure);
            app.PutmatchesingeometricrelationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PutmatchesingeometricrelationCheckBoxValueChanged, true);
            app.PutmatchesingeometricrelationCheckBox.Text = 'Put matches in geometric relation';
            app.FindKeypointsButton = uibutton(app.UIFigure, 'push');
            app.FindKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindKeypointsButtonPushed, true);
            app.FindKeypointsButton.Text = 'Find Keypoints';
            app.ReferenceImage = uiaxes(app.UIFigure);
            title(app.ReferenceImage, {''; ''; ''})
            xlabel(app.ReferenceImage, {''; ''})
            ylabel(app.ReferenceImage, {''; ''})
            app.ReferenceImage.XTick = [];
            app.ReferenceImage.YTick = [];
            app.TargetImage = uiaxes(app.UIFigure);
            title(app.TargetImage, {''; ''; ''})
            xlabel(app.TargetImage, {''; ''})
            ylabel(app.TargetImage, {''; ''})
            app.TargetImage.XTick = [];
            app.TargetImage.YTick = [];
            app.ResultImage = uiaxes(app.UIFigure);
            title(app.ResultImage, {''; ''; ''})
            xlabel(app.ResultImage, {''; ''})
            ylabel(app.ResultImage, {''; ''})
            app.ResultImage.XTick = [];
            app.ResultImage.YTick = [];
            app.MatchKeypointsButton = uibutton(app.UIFigure, 'push');
            app.MatchKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @MatchKeypointsButtonPushed, true);
            app.MatchKeypointsButton.Text = 'Match Keypoints';
            app.ToggleWebcamButtonTarget = uibutton(app.UIFigure, 'state');
            app.ToggleWebcamButtonTarget.ValueChangedFcn = createCallbackFcn(app, @WebcamToggleTarget, true);
            app.ToggleWebcamButtonTarget.Text = 'Toggle Webcam';
            app.ToggleWebcamButtonReference = uibutton(app.UIFigure, 'state');
            app.ToggleWebcamButtonReference.ValueChangedFcn = createCallbackFcn(app, @WebcamToggleReference, true);
            app.ToggleWebcamButtonReference.Text = 'Toggle Webcam';
            app.ReferenceImageMenuButton = uibutton(app.UIFigure, 'push');
            app.ReferenceImageMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceImageMenuButtonPushed, true);
            app.ReferenceImageMenuButton.Text = 'Reference Image Menu';
            app.TargetImageMenuButton = uibutton(app.UIFigure, 'push');
            app.TargetImageMenuButton.Text = 'Target Image Menu';
            app.NumberofshownKeypointsSliderLabel = uilabel(app.UIFigure);
            app.NumberofshownKeypointsSliderLabel.Text = {'Number of '; 'shown Keypoints'};
            app.NumberofshownKeypointsSlider = uislider(app.UIFigure);
            app.NumberofshownKeypointsSlider.Limits = [1 1000];
            app.NumberofshownKeypointsSlider.MajorTicks = [1 330 660 1000];
            app.NumberofshownKeypointsSlider.Value = 100;
            app.ShowBoundingBoxCheckBox = uicheckbox(app.UIFigure);
            app.ShowBoundingBoxCheckBox.Enable = 'off';
            app.ShowBoundingBoxCheckBox.Text = 'Show Bounding Box';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.ReferenceImageMenuButton.ContextMenu = app.ContextMenu;
            app.SelectImageMenu = uimenu(app.ContextMenu);
            app.SelectImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectReferenceButtonPushed, true);
            app.SelectImageMenu.Text = 'Select Image';
            app.SaveImageMenu = uimenu(app.ContextMenu);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveReferenceButtonPushed, true);
            app.SaveImageMenu.Text = 'Save Image';
            app.CropImageMenu = uimenu(app.ContextMenu);
            app.CropImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ReferenceCropButtonPushed, true);
            app.CropImageMenu.Text = 'Crop Image';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu2
            app.TargetImageMenuButton.ContextMenu = app.ContextMenu2;
            app.SelectImageMenu_2 = uimenu(app.ContextMenu2);
            app.SelectImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectTargetButtonPushed, true);
            app.SelectImageMenu_2.Text = 'Select Image';
            app.SaveImageMenu_2 = uimenu(app.ContextMenu2);
            app.SaveImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveTargetButtonPushed, true);
            app.SaveImageMenu_2.Text = 'Save Image';
            app.CropImageMenu_2 = uimenu(app.ContextMenu2);
            app.CropImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TargetCropButtonPushed, true);
            app.CropImageMenu_2.Text = 'Crop Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end